classdef data_acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAcquisitionUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout1                     matlab.ui.container.GridLayout
        ConnectPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        DevicesDropDown                 matlab.ui.control.DropDown
        ConnectButton                   matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        MethodPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        RunButton                       matlab.ui.control.Button
        TechniqueDropDown               matlab.ui.control.DropDown
        MeasuretechniquesettingsPanel   matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EISSettingsPanel                matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        HzLabel_2                       matlab.ui.control.Label
        EISMaxFrequencyEditField        matlab.ui.control.NumericEditField
        MaxfrequencyEditFieldLabel      matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        EISMinFrequencyEditField        matlab.ui.control.NumericEditField
        MinfrequencyEditFieldLabel      matlab.ui.control.Label
        EISnFrequenciesEditField        matlab.ui.control.NumericEditField
        nfrequenciesEditFieldLabel      matlab.ui.control.Label
        FrequencyscanLabel              matlab.ui.control.Label
        VLabel_12                       matlab.ui.control.Label
        EISEACEditField                 matlab.ui.control.NumericEditField
        EACEditFieldLabel               matlab.ui.control.Label
        VLabel_11                       matlab.ui.control.Label
        EISEDCEditField                 matlab.ui.control.NumericEditField
        EDCEditFieldLabel_2             matlab.ui.control.Label
        FixedpotentialLabel             matlab.ui.control.Label
        CVSettingsPanel                 matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        CVNumberOfScansEditField        matlab.ui.control.NumericEditField
        NumberofscansEditFieldLabel     matlab.ui.control.Label
        VsLabel_2                       matlab.ui.control.Label
        CVScanRateEditField             matlab.ui.control.NumericEditField
        ScanrateEditField_2Label        matlab.ui.control.Label
        VLabel_10                       matlab.ui.control.Label
        CVEstepEditField                matlab.ui.control.NumericEditField
        EstepEditField_2Label           matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        CVEvertex2EditField             matlab.ui.control.NumericEditField
        Evertex2EditFieldLabel          matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        CVEvertex1EditField             matlab.ui.control.NumericEditField
        Evertex1EditFieldLabel          matlab.ui.control.Label
        VLabel_7                        matlab.ui.control.Label
        CVEbeginEditField               matlab.ui.control.NumericEditField
        EbeginEditField_2Label          matlab.ui.control.Label
        tequilibrationEditField_2Label  matlab.ui.control.Label
        CASettingsPanel                 matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        sLabel_6                        matlab.ui.control.Label
        CAtRunEditField                 matlab.ui.control.NumericEditField
        trunEditFieldLabel              matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        CAtIntervalEditField            matlab.ui.control.NumericEditField
        tintervalEditFieldLabel         matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        CAEDCEditField                  matlab.ui.control.NumericEditField
        EDCEditFieldLabel               matlab.ui.control.Label
        tEquilibrationEditFieldLabel    matlab.ui.control.Label
        PretreatmentsettingsPanel       matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        sLabel_8                        matlab.ui.control.Label
        tequilibrationEditFieldLabel_2  matlab.ui.control.Label
        tEquilibrationEditField         matlab.ui.control.NumericEditField
        sLabel_2                        matlab.ui.control.Label
        tdepositionEditField            matlab.ui.control.NumericEditField
        tdepositionEditFieldLabel       matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        EdepositionEditField            matlab.ui.control.NumericEditField
        EdepositionEditFieldLabel       matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        tconditionEditField             matlab.ui.control.NumericEditField
        tconditionEditFieldLabel        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        EconditionEditField             matlab.ui.control.NumericEditField
        EconditionEditFieldLabel        matlab.ui.control.Label
        LSVSettingsPanel                matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        VLabel_5                        matlab.ui.control.Label
        LSVEstepEditField               matlab.ui.control.NumericEditField
        EstepEditFieldLabel             matlab.ui.control.Label
        VsLabel                         matlab.ui.control.Label
        LSVScanRateEditField            matlab.ui.control.NumericEditField
        ScanrateEditFieldLabel          matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        LSVEendEditField                matlab.ui.control.NumericEditField
        EendEditFieldLabel              matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        LSVEbeginEditField              matlab.ui.control.NumericEditField
        EbeginEditFieldLabel            matlab.ui.control.Label
        tequilibrationEditFieldLabel    matlab.ui.control.Label
        OtherSettingsPanel              matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        CurrentRangePanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        StartDropDown                   matlab.ui.control.DropDown
        StartDropDownLabel              matlab.ui.control.Label
        MaxDropDown                     matlab.ui.control.DropDown
        MaxDropDownLabel                matlab.ui.control.Label
        MinDropDown                     matlab.ui.control.DropDown
        MinDropDownLabel                matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        LogBox                          matlab.ui.control.ListBox
        LogListBoxLabel                 matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        curSettings;
    end

    methods (Access = private)

        function log(app, message)
            message = string(datetime) + " " + message;
            if(isempty(app.LogBox.Items))
                app.LogBox.Items = cellstr(message);
            else
                app.LogBox.Items(end+1) = cellstr(message);
            end
            app.LogBox.Value = app.LogBox.Items(end);
        end

        function loadSDK(app)
            log(app, "Checking settings.mat");
            fType = exist("settings.mat", "file");
            if(fType == 2)
                log(app, "Loading settings.mat");
                load("settings.mat", "Settings");
                % Check whether settings contains the required field.
                if(isfield(Settings.Default, 'dllPath') == 1)
                    % Check whether the previously specified folder
                    % contains the PalmSens Core dll.
                    dllPath = Settings.Default.dllPath;
                    fType = exist(dllPath, 'file');
                    if(fType ~= 2)
                        fType = 0;
                    end
                else
                    fType = 0;
                end
            end
            % Prompt location of SDK library
            while(fType == 0)
                log(app, "Please specify the location of "...
                    + "PalmSens.Core.Matlab.dll");
                [dllName, dllFolder] =...
                    uigetfile("PalmSens.Core.Matlab.dll",...
                    "Please specify the location of "...
                    + "PalmSens.Core.Matlab.dll");
                % Check whether a file was selected.
                if(isempty(dllFolder))
                    log(app, "Error: no file selected.");
                    continue;
                end
                % Double check to determine if the selected file exists.
                dllPath = fullfile(dllFolder, dllName);
                fType = exist(dllPath, 'file');
                if(fType ~= 2)
                    fType = 0;
                end
                % Check to determine whether the correct
                % file was specified.
                if(dllName ~= "PalmSens.Core.Matlab.dll")
                    log(app, "Please select the PalmSens SDK "...
                        + "library named PalmSens.Core.Matlab.dll");
                    continue;
                end
            end
            % Store path of SDK library in settings.
            Settings.Default.dllPath = dllPath;
            if(exist('settings.mat', 'file') ~= 2)
                save('settings.mat', 'Settings');
            else
                save('settings.mat', 'Settings', '-append');
            end
            % Load PalmSens SDK library.
            Settings.PSSDK = NET.addAssembly(dllPath);
            log(app, "PalmSens SDK loaded succesfully.");
            % Update settings.
            app.curSettings = Settings;
            log(app, "PalmSens SDK location saved.");
        end

        function scanDevices(app)
            % Get connected devices.
            deviceList = getConnectedDevices(app);
            nDevices = deviceList.Count;
            log(app, num2str(nDevices) +...
                " device(s) connected:");
            devices = cell(nDevices);
            % Display connected devices.
            for ii = 0:nDevices-1
                log(app, num2str(ii+1) + " - " +...
                    char(deviceList.Item(ii).ToString()));
                devices{ii+1} = char(deviceList.Item(ii).ToString());
            end
            % Disable/enable connect button depending
            % on the presence of devices.
            if(nDevices == 0)
                app.ConnectButton.Enable = "off";
                devices = cellstr("No Devices Detected");
            else
                app.ConnectButton.Enable = "on";
            end
            % Update popup menu and store device information.
            app.DevicesDropDown.Items = devices;
            app.curSettings.Devices.deviceList = deviceList;
            app.curSettings.Devices.nDevices = nDevices;
        end

        function createMethod(app, technique)
            % Create a new method of the specified type.
            method = newMethod(app, technique);
            updateMethodFields(app, method, technique);
        end

        function deviceList = getConnectedDevices(~,...
                bluetooth, serial)
            % GetConnectedDevices returns a list of all
            % the connected comm devices.
            % Optional Inputs:
            % - bluetooth: a boolean that when set
            %              to true enables scanning for bluetooth.
            %              Setting bluetooth to false
            %              is faster. (By default scanning
            %              for bluetooth is disabled)
            % - serial: a boolean that when set to true
            %           enables scanning for serial
            %           devices. (By default scanning
            %           for serial devices is disabled)
            % Output:
            % - deviceList: List objects <PalmSens.Devices.Device>
            %               representing the connected devices.

            % Check input parameters.
            if(nargin == 2)
                serial = false;
            end
            if(nargin == 1)
                serial = false;
                bluetooth = false;
            end
            % Create an empty device list.
            deviceList = NET.createGeneric(...
                'System.Collections.Generic.List',...
                {'PalmSens.Devices.Device'});
            % Create an error string, required as an input parameter.
            errors = System.String('');
            % Scan for connected devices.
            listUSBCDC = PalmSens.Windows.Devices...
                .USBCDCDevice.DiscoverDevicesMatlab(errors);
            listFTDI = PalmSens.Windows.Devices...
                .FTDIDevice.DiscoverDevices(errors);
            % Add devices to connected device list.
            deviceList.AddRange(listUSBCDC); % PalmSens 4
            deviceList.AddRange(listFTDI); % PalmSens 3 and Emstats
            % Scan for and add bluetooth devices
            % to connected device list.
            if(bluetooth == true)
                listBluetooth = PalmSens.Windows.Devices...
                    .BluetoothDevice.DiscoverDevices(errors);
                deviceList.AddRange(listBluetooth);
            end
            % Scan for and add serial devices
            % to connected device list.
            if(serial == true)
                listSerial = PalmSens.Windows.Devices...
                    .SerialPortDevice.DiscoverDevices(errors);
                deviceList.AddRange(listSerial);
            end
            % Remove duplicates from the connected device list.
            n = deviceList.Count - 1;
            i = 0;
            while(i <= n)
                j = i + 1;
                str = deviceList.Item(i).ToString();
                while(j <= n)
                    if(str == deviceList.Item(j).ToString())
                        deviceList.RemoveAt(j);
                        n = deviceList.Count - 1;
                    else
                        j = j + 1;
                    end
                end
                i = i + 1;
            end
        end

        function method = newMethod(~, technique)
            % newMethod instantiates a new method object
            % of the specified technique.
            % Input:
            % - technique: a number specifying the index
            %              of one of the following techniques.
            %     1.  Linear Sweep Voltammetry
            %     2.  Cyclic Voltammetry
            %     3.  AC Voltammetry
            %     4.  Differential Pulse Voltammetry
            %     5.  Square Wave Voltammetry
            %     6.  Normal Pulse Voltammetry
            %     7.  Chronoamperometry
            %     8.  MultiStep Amperometry
            %     9.  Fast Amperometry
            %     10. Pulsed Amperometric Detection
            %     11. Multiple Pulse Amperometry
            %     12. Open Circuit Potentiometry
            %     13. Chronopotentiometry
            %     14. MultiStep Potentiometry
            %     15. Chronopotentiometric Stripping
            %     16. Impedance Spectroscopy (PS3 and PS4 only)
            % Output:
            % - method: the respective tecnique's method
            %           object containing its parameters.
            %           (if an invalid method is specified
            %           this will return false)
            method = false;
            % Check whether a valid technique was specified.
            if(technique < 1 || technique > 17)
                return;
            end
            if(technique == 1)
                method = PalmSens.Techniques.LinearSweep();
            elseif(technique == 2)
                method = PalmSens.Techniques.CyclicVoltammetry();
            elseif(technique == 3)
                method = PalmSens.Techniques.ACVoltammetry();
            elseif(technique == 4)
                method = PalmSens.Techniques.DifferentialPulse();
            elseif(technique == 5)
                method = PalmSens.Techniques.SquareWave();
            elseif(technique == 6)
                method = PalmSens.Techniques.NormalPulse();
            elseif(technique == 7)
                method = PalmSens.Techniques.AmperometricDetection();
            elseif(technique == 8)
                method = PalmSens.Techniques.MultistepAmperometry();
            elseif(technique == 9)
                method = PalmSens.Techniques.FastAmperometry();
            elseif(technique == 10)
                method = PalmSens.Techniques.PulsedAmpDetection();
            elseif(technique == 11)
                method = PalmSens.Techniques.MultiplePulseAmperometry();
            elseif(technique == 12)
                method = PalmSens.Techniques.OpenCircuitPotentiometry();
            elseif(technique == 13)
                method = PalmSens.Techniques.Potentiometry();
            elseif(technique == 14)
                method = PalmSens.Techniques.MultistepPotentiometry();
            elseif(technique == 15)
                method = PalmSens.Techniques.ChronoPotStripping();
            elseif(technique == 16)
                method = PalmSens.Techniques.ImpedimetricMethod();
            elseif(technique == 17)
                method = PalmSens.Techniques.FastCyclicVoltammetry();
            end
        end

        function updateMethodFields(app, method, technique)
            % Update the gui fields with the methods settings.
            % Set the current range.
            setCurrentRange(app, method);
            % Set the pretreatment settings.
            setPreTreatmentSettings(app, method);
            % This example only includes method specific fields
            % for LSV, CV and EIS. Other panels can be added
            % using GUIDE and the respective functions to
            % update their fields can be added here.
            if(technique == 1)
                updateLSVMethodFields(app, method);
            elseif(technique == 2)
                updateCVMethodFields(app, method);
            elseif(technique == 7)
                updateCAMethodFields(app, method);
            elseif(technique == 16)
                updateEISMethodFields(app, method);
            else
                updateOtherMethodFields(app, method);
            end
            % Store the method file in the figure.
            app.curSettings.Method.method = method;
        end
        
        function setCurrentRange(app, method)
            % Check if current range is fixed or auto.
            fixedCR = isa(method.Ranging, "PalmSens.AutoRanging")...
                == false;
            if(fixedCR)
                index = 8 - method.Ranging.MaximumCurrentRange.CRbyte;
                if(index > 8)
                    index = 8;
                elseif (index < 1)
                    index = 1;
                end
                app.MaxDropDown.Value = index;
                app.MinDropDown.Value = index;
                app.StartDropDown.Value = index;
            else
                min = 8 - method.Ranging.MinimumCurrentRange.CRbyte;
                if(min > 8)
                    min = 8;
                elseif (min < 1)
                    min = 1;
                end
                max = 8 - method.Ranging.MaximumCurrentRange.CRbyte;
                if(max > 8)
                    max = 8;
                elseif (max < 1)
                    max = 1;
                end
                start = 8 - method.Ranging.StartCurrentRange.CRbyte;
                if(start > 8)
                    start = 8;
                elseif (start < 1)
                    start = 1;
                end
                app.MaxDropDown.Value = max;
                app.MinDropDown.Value = min;
                app.StartDropDown.Value = start;
            end
        end
        
        function setPreTreatmentSettings(app, method)
            app.EconditionEditField.Value =...
                double(method.ConditioningPotential);
            app.tconditionEditField.Value =...
                double(method.ConditioningTime);
            app.EdepositionEditField.Value =...
                double(method.DepositionPotential);
            app.tdepositionEditField.Value =...
                double(method.DepositionTime);
            app.tEquilibrationEditField.Value =...
                double(method.EquilibrationTime);
        end
        
        function updateLSVMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the LSV settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "on";
            % Set the LSV specific settings.
            app.LSVEbeginEditField.Value =...
                double(method.BeginPotential);
            app.LSVEendEditField.Value =...
                double(method.EndPotential);
            app.LSVEstepEditField.Value =...
                double(method.StepPotential);
            app.LSVScanRateEditField.Value =...
                double(method.Scanrate);
        end
        
        function updateCAMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the CA settings panel.
            app.CASettingsPanel.Visible = "on";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "off";
            %Set the CA specific settings
            app.CAEDCEditField.Value =...
                double(method.BeginPotential);
            app.CAtIntervalEditField.Value =...
                double(method.IntervalTime);
            app.CAtRunEditField.Value =...
                double(method.RunTime);
        end
        
        function updateCVMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the CV settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "on";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "off";
            % Set the CV specific settings.
            app.CVEbeginEditField.Value =...
                double(method.BeginPotential);
            app.CVEvertex1EditField.Value =...
                double(method.Vtx1Potential);
            app.CVEvertex2EditField.Value =...
                double(method.Vtx2Potential);
            app.CVEstepEditField.Value =...
                double(method.StepPotential);
            app.CVScanRateEditField.Value =...
                double(method.Scanrate);
            app.CVNumberOfScansEditField.Value =...
                double(method.nScans);
        end
        
        function updateEISMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the EIS settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "on";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "off";
            % Set the EIS specific settings.
            app.EISEDCEditField.Value =...
                num2str(method.Potential);
            app.EISEACEditField.Value =...
                num2str(method.Eac);
            app.EISnFrequenciesEditField.Value =...
                num2str(method.nFrequencies);
            app.EISMinFrequencyEditField.Value =...
                num2str(method.MinFrequency);
            app.EISMaxFrequencyEditField.Value =...
                num2str(method.MaxFrequency);
            % Set the methods scantype to fixed
            % potential en frequency scan.
            method.ScanType =...
                PalmSens.Techniques.enumScanType.FixedPotential;
            method.FreqType =...
                PalmSens.Techniques.enumFrequencyType.Fixed;
            method.FixedFrequency = 40000;
            method.RunTime = 0;
        end
        
        function updateOtherMethodFields(app, ~)
            % Hide the other techniques settings panels
            % and show the other settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "on";
            app.LSVSettingsPanel.Visible = "off";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reset LogBox.
            app.LogBox.Items = {};
            % Assign TechniqueDropDown data.
            app.TechniqueDropDown.ItemsData =...
                1:length(app.TechniqueDropDown.Items);
            % Assign range drop down data.
            app.MinDropDown.ItemsData =...
                1:length(app.MinDropDown.Items);
            app.MaxDropDown.ItemsData =...
                1:length(app.MaxDropDown.Items);
            app.StartDropDown.ItemsData =...
                1:length(app.StartDropDown.Items);
            % Load SDK.
            loadSDK(app);
            % Scan devices.
            scanDevices(app);
            % Initcialize methods.
            createMethod(app, app.TechniqueDropDown.Value);
            % Disable the run measurement button by default.
            app.RunButton.Enable = "off";
            app.SaveButton.Enable = "off";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DataAcquisitionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {880, 880, 880};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {880, 880};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x', 172};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAcquisitionUIFigure = uifigure('Visible', 'off');
            app.DataAcquisitionUIFigure.Name = 'Data Acquisition';
            app.DataAcquisitionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DataAcquisitionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.LeftPanel);
            app.LogListBoxLabel = uilabel(app.GridLayout1);
            app.LogListBoxLabel.Text = 'Log';
            app.LogBox = uilistbox(app.GridLayout1);
            app.MethodPanel = uipanel(app.GridLayout1);
            app.MethodPanel.Title = 'Method';
            app.GridLayout2 = uigridlayout(app.MethodPanel);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.Text = 'Save';
            app.CurrentRangePanel = uipanel(app.GridLayout2);
            app.CurrentRangePanel.Title = 'Current range';
            app.GridLayout4 = uigridlayout(app.CurrentRangePanel);
            app.MinDropDownLabel = uilabel(app.GridLayout4);
            app.MinDropDownLabel.Text = 'Min';
            app.MinDropDown = uidropdown(app.GridLayout4);
            app.MinDropDown.Items = {'10mA', '1mA', '100uA', '10uA', '1uA', '100nA', '10nA', '1nA'};
            app.MinDropDown.Value = '10mA';
            app.MaxDropDownLabel = uilabel(app.GridLayout4);
            app.MaxDropDownLabel.Text = 'Max';
            app.MaxDropDown = uidropdown(app.GridLayout4);
            app.MaxDropDown.Items = {'10mA', '1mA', '100uA', '10uA', '1uA', '100nA', '10nA', '1nA'};
            app.MaxDropDown.Value = '10mA';
            app.StartDropDownLabel = uilabel(app.GridLayout4);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.GridLayout4);
            app.StartDropDown.Items = {'10mA', '1mA', '100uA', '10uA', '1uA', '100nA', '10nA', '1nA'};
            app.StartDropDown.Value = '10mA';
            app.MeasuretechniquesettingsPanel = uipanel(app.GridLayout2);
            app.MeasuretechniquesettingsPanel.Title = 'Measure technique settings';
            app.GridLayout6 = uigridlayout(app.MeasuretechniquesettingsPanel);
            app.OtherSettingsPanel = uipanel(app.GridLayout6);
            app.OtherSettingsPanel.Title = 'Settings';
            app.GridLayout8 = uigridlayout(app.OtherSettingsPanel);
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = {'This example does not include the controls to view/edit this measurement technique. '; ''; 'Follow the examples of the LSV, CV and EIS panels to create your own panel for this type of measurement technique.'};
            app.LSVSettingsPanel = uipanel(app.GridLayout6);
            app.LSVSettingsPanel.Title = 'Linear Sweep Voltammetry Settings';
            app.GridLayout9 = uigridlayout(app.LSVSettingsPanel);
            app.tequilibrationEditFieldLabel = uilabel(app.GridLayout9);
            app.tequilibrationEditFieldLabel.Text = 't equilibration';
            app.EbeginEditFieldLabel = uilabel(app.GridLayout9);
            app.EbeginEditFieldLabel.Text = 'E begin';
            app.LSVEbeginEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VLabel_3 = uilabel(app.GridLayout9);
            app.VLabel_3.Text = 'V';
            app.EendEditFieldLabel = uilabel(app.GridLayout9);
            app.EendEditFieldLabel.Text = 'E end';
            app.LSVEendEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VLabel_4 = uilabel(app.GridLayout9);
            app.VLabel_4.Text = 'V';
            app.ScanrateEditFieldLabel = uilabel(app.GridLayout9);
            app.ScanrateEditFieldLabel.Text = 'Scan rate';
            app.LSVScanRateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VsLabel = uilabel(app.GridLayout9);
            app.VsLabel.Text = 'V/s';
            app.EstepEditFieldLabel = uilabel(app.GridLayout9);
            app.EstepEditFieldLabel.Text = 'E step';
            app.LSVEstepEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VLabel_5 = uilabel(app.GridLayout9);
            app.VLabel_5.Text = 'V';
            app.PretreatmentsettingsPanel = uipanel(app.GridLayout6);
            app.PretreatmentsettingsPanel.Title = 'Pretreatment settings';
            app.GridLayout7 = uigridlayout(app.PretreatmentsettingsPanel);
            app.EconditionEditFieldLabel = uilabel(app.GridLayout7);
            app.EconditionEditFieldLabel.Text = 'E condition';
            app.EconditionEditField = uieditfield(app.GridLayout7, 'numeric');
            app.VLabel = uilabel(app.GridLayout7);
            app.VLabel.Text = 'V';
            app.tconditionEditFieldLabel = uilabel(app.GridLayout7);
            app.tconditionEditFieldLabel.Text = 't condition';
            app.tconditionEditField = uieditfield(app.GridLayout7, 'numeric');
            app.sLabel = uilabel(app.GridLayout7);
            app.sLabel.Text = 's';
            app.EdepositionEditFieldLabel = uilabel(app.GridLayout7);
            app.EdepositionEditFieldLabel.Text = 'E deposition';
            app.EdepositionEditField = uieditfield(app.GridLayout7, 'numeric');
            app.VLabel_2 = uilabel(app.GridLayout7);
            app.VLabel_2.Text = 'V';
            app.tdepositionEditFieldLabel = uilabel(app.GridLayout7);
            app.tdepositionEditFieldLabel.Text = 't deposition';
            app.tdepositionEditField = uieditfield(app.GridLayout7, 'numeric');
            app.sLabel_2 = uilabel(app.GridLayout7);
            app.sLabel_2.Text = 's';
            app.tEquilibrationEditField = uieditfield(app.GridLayout7, 'numeric');
            app.tequilibrationEditFieldLabel_2 = uilabel(app.GridLayout7);
            app.tequilibrationEditFieldLabel_2.Text = 't equilibration';
            app.sLabel_8 = uilabel(app.GridLayout7);
            app.sLabel_8.Text = 's';
            app.CASettingsPanel = uipanel(app.GridLayout6);
            app.CASettingsPanel.Title = 'Chronoamperometry settings';
            app.GridLayout10 = uigridlayout(app.CASettingsPanel);
            app.tEquilibrationEditFieldLabel = uilabel(app.GridLayout10);
            app.tEquilibrationEditFieldLabel.Text = 't Equilibration';
            app.EDCEditFieldLabel = uilabel(app.GridLayout10);
            app.EDCEditFieldLabel.Text = 'E DC';
            app.CAEDCEditField = uieditfield(app.GridLayout10, 'numeric');
            app.VLabel_6 = uilabel(app.GridLayout10);
            app.VLabel_6.Text = 'V';
            app.tintervalEditFieldLabel = uilabel(app.GridLayout10);
            app.tintervalEditFieldLabel.Text = 't interval';
            app.CAtIntervalEditField = uieditfield(app.GridLayout10, 'numeric');
            app.sLabel_5 = uilabel(app.GridLayout10);
            app.sLabel_5.Text = 's';
            app.trunEditFieldLabel = uilabel(app.GridLayout10);
            app.trunEditFieldLabel.Text = 't run';
            app.CAtRunEditField = uieditfield(app.GridLayout10, 'numeric');
            app.sLabel_6 = uilabel(app.GridLayout10);
            app.sLabel_6.Text = 's';
            app.CVSettingsPanel = uipanel(app.GridLayout6);
            app.CVSettingsPanel.Title = 'Cyclic voltammetry settings';
            app.GridLayout11 = uigridlayout(app.CVSettingsPanel);
            app.tequilibrationEditField_2Label = uilabel(app.GridLayout11);
            app.tequilibrationEditField_2Label.Text = 't equilibration';
            app.EbeginEditField_2Label = uilabel(app.GridLayout11);
            app.EbeginEditField_2Label.Text = 'E begin';
            app.CVEbeginEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_7 = uilabel(app.GridLayout11);
            app.VLabel_7.Text = 'V';
            app.Evertex1EditFieldLabel = uilabel(app.GridLayout11);
            app.Evertex1EditFieldLabel.Text = 'E vertex 1';
            app.CVEvertex1EditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_8 = uilabel(app.GridLayout11);
            app.VLabel_8.Text = 'V';
            app.Evertex2EditFieldLabel = uilabel(app.GridLayout11);
            app.Evertex2EditFieldLabel.Text = 'E vertex 2';
            app.CVEvertex2EditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_9 = uilabel(app.GridLayout11);
            app.VLabel_9.Text = 'V';
            app.EstepEditField_2Label = uilabel(app.GridLayout11);
            app.EstepEditField_2Label.Text = 'E step';
            app.CVEstepEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_10 = uilabel(app.GridLayout11);
            app.VLabel_10.Text = 'V';
            app.ScanrateEditField_2Label = uilabel(app.GridLayout11);
            app.ScanrateEditField_2Label.Text = 'Scan rate';
            app.CVScanRateEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VsLabel_2 = uilabel(app.GridLayout11);
            app.VsLabel_2.Text = 'V/s';
            app.NumberofscansEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberofscansEditFieldLabel.Text = 'Number of scans';
            app.CVNumberOfScansEditField = uieditfield(app.GridLayout11, 'numeric');
            app.EISSettingsPanel = uipanel(app.GridLayout6);
            app.EISSettingsPanel.Title = 'Impedance spectroscopy settings';
            app.GridLayout12 = uigridlayout(app.EISSettingsPanel);
            app.FixedpotentialLabel = uilabel(app.GridLayout12);
            app.FixedpotentialLabel.Text = 'Fixed potential:';
            app.EDCEditFieldLabel_2 = uilabel(app.GridLayout12);
            app.EDCEditFieldLabel_2.Text = 'E DC';
            app.EISEDCEditField = uieditfield(app.GridLayout12, 'numeric');
            app.VLabel_11 = uilabel(app.GridLayout12);
            app.VLabel_11.Text = 'V';
            app.EACEditFieldLabel = uilabel(app.GridLayout12);
            app.EACEditFieldLabel.Text = 'E AC';
            app.EISEACEditField = uieditfield(app.GridLayout12, 'numeric');
            app.VLabel_12 = uilabel(app.GridLayout12);
            app.VLabel_12.Text = 'V';
            app.FrequencyscanLabel = uilabel(app.GridLayout12);
            app.FrequencyscanLabel.Text = 'Frequency scan:';
            app.nfrequenciesEditFieldLabel = uilabel(app.GridLayout12);
            app.nfrequenciesEditFieldLabel.Text = 'n frequencies';
            app.EISnFrequenciesEditField = uieditfield(app.GridLayout12, 'numeric');
            app.MinfrequencyEditFieldLabel = uilabel(app.GridLayout12);
            app.MinfrequencyEditFieldLabel.Text = 'Min frequency';
            app.EISMinFrequencyEditField = uieditfield(app.GridLayout12, 'numeric');
            app.HzLabel = uilabel(app.GridLayout12);
            app.HzLabel.Text = 'Hz';
            app.MaxfrequencyEditFieldLabel = uilabel(app.GridLayout12);
            app.MaxfrequencyEditFieldLabel.Text = 'Max frequency';
            app.EISMaxFrequencyEditField = uieditfield(app.GridLayout12, 'numeric');
            app.HzLabel_2 = uilabel(app.GridLayout12);
            app.HzLabel_2.Text = 'Hz';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.TechniqueDropDown = uidropdown(app.GridLayout5);
            app.TechniqueDropDown.Items = {'Linear Sweep Voltammetry', 'Cyclic Voltammetry', 'AC Voltammetry    ', 'Differential Pulse Voltammetry    ', 'Square Wave Voltammetry', 'Normal Pulse Voltammetry', 'Chronoamperometry', 'MultiStep Amperometry', 'Fast Amperometry', 'Pulsed Amperometric Detection', 'Multiple Pulse Amperometry', 'Open Circuit Potentiometry', 'Chronopotentiometry', 'MultiStep Potentiometry', 'Chronopotentiometric Stripping', 'Impedance Spectroscopy (PS3 and PS4 only)'};
            app.TechniqueDropDown.Value = 'Linear Sweep Voltammetry';
            app.RunButton = uibutton(app.GridLayout5, 'push');
            app.RunButton.Text = 'Run';
            app.ConnectPanel = uipanel(app.GridLayout1);
            app.ConnectPanel.Title = 'Connect to device';
            app.GridLayout3 = uigridlayout(app.ConnectPanel);
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.Text = 'Connect';
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.DataAcquisitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_acquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataAcquisitionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DataAcquisitionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAcquisitionUIFigure)
        end
    end
end